classdef PG_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure             % UI Figure
        UIAxes        matlab.ui.control.UIAxes     % Title
        LabelDropDown matlab.ui.control.Label      % Drop Down
        DropDown      matlab.ui.control.DropDown   % Option 1, Option 2, Op...
        Label         matlab.ui.control.Label      % GraphTitle
        Panel2        matlab.ui.container.Panel    % Load Profile Description
        Label14       matlab.ui.control.Label      % ProfileName
        Label13       matlab.ui.control.Label      % Profile Description
        Label33       matlab.ui.control.Label      % Link to Source
        TabGroup      matlab.ui.container.TabGroup % EconomicMetrics, Techn...
        Economic      matlab.ui.container.Tab      % EconomicMetrics
        Label23       matlab.ui.control.Label      % VALUE
        Label24       matlab.ui.control.Label      % VALUE
        Label25       matlab.ui.control.Label      % VALUE
        Label26       matlab.ui.control.Label      % VALUE
        Label27       matlab.ui.control.Label      % Per Unit Cost
        Label28       matlab.ui.control.Label      % Total kWh
        Label29       matlab.ui.control.Label      % Fixed Cost
        Label30       matlab.ui.control.Label      % Semi-Fixed
        Label31       matlab.ui.control.Label      % Variable
        Label32       matlab.ui.control.Label      % VALUE
        Technical     matlab.ui.container.Tab      % TechnicalMetrics
        Label15       matlab.ui.control.Label      % Avg Load
        Label16       matlab.ui.control.Label      % Peak Load
        Label17       matlab.ui.control.Label      % Units Consumed
        Label18       matlab.ui.control.Label      % Max Demand
        Label19       matlab.ui.control.Label      % VALUE
        Label20       matlab.ui.control.Label      % VALUE
        Label21       matlab.ui.control.Label      % VALUE
        Label22       matlab.ui.control.Label      % VALUE
    end


    properties (Access = private)
        loadProfileData;         % MATLAB table
        loadProfileNames;        % Cell array of profile names
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Read the load profiles as a table with column headers specifying profile name
            app.loadProfileData = readtable('../load-profiles/all-load-profiles.xlsx');
            
            % Extract names of load profiles from table
            app.loadProfileNames = app.loadProfileData.Properties.VariableNames;
            
            % Populate dropdown with names of load profiles
            app.DropDown.Items = app.loadProfileNames;
      
            % Plot first load profile by default on startup
            plot(app.UIAxes, app.loadProfileData.(app.loadProfileNames{1}));
        end

        % DropDown value changed function
        function DropDownValueChanged(app, event)
            % Get name of selected drop down value 
            profileName = event.Value;
            
            % Use this to extract the right data 
            plot(app.UIAxes, app.loadProfileData.(profileName));
            
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title');
            xlabel(app.UIAxes, 'X');
            ylabel(app.UIAxes, 'Y');
            app.LabelDropDown = uilabel(app.UIFigure);
            app.LabelDropDown.Text = 'Drop Down';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'GraphTitle';
            app.Panel2 = uipanel(app.UIFigure);
            app.Panel2.Title = 'Load Profile Description';
            app.Panel2.Units = 'pixels';
            app.Label14 = uilabel(app.Panel2);
            app.Label14.Text = 'ProfileName';
            app.Label13 = uilabel(app.Panel2);
            app.Label13.Text = 'Profile Description';
            app.Label33 = uilabel(app.Panel2);
            app.Label33.Text = 'Link to Source';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.Units = 'pixels';
            app.Economic = uitab(app.TabGroup);
            app.Economic.Units = 'pixels';
            app.Economic.Title = 'EconomicMetrics';
            app.Label23 = uilabel(app.Economic);
            app.Label23.Text = 'VALUE';
            app.Label24 = uilabel(app.Economic);
            app.Label24.Text = 'VALUE';
            app.Label25 = uilabel(app.Economic);
            app.Label25.Text = 'VALUE';
            app.Label26 = uilabel(app.Economic);
            app.Label26.Text = 'VALUE';
            app.Label27 = uilabel(app.Economic);
            app.Label27.Text = 'Per Unit Cost';
            app.Label28 = uilabel(app.Economic);
            app.Label28.Text = 'Total kWh';
            app.Label29 = uilabel(app.Economic);
            app.Label29.Text = 'Fixed Cost';
            app.Label30 = uilabel(app.Economic);
            app.Label30.Text = 'Semi-Fixed';
            app.Label31 = uilabel(app.Economic);
            app.Label31.Text = 'Variable';
            app.Label32 = uilabel(app.Economic);
            app.Label32.Text = 'VALUE';
            app.Technical = uitab(app.TabGroup);
            app.Technical.Units = 'pixels';
            app.Technical.Title = 'TechnicalMetrics';
            app.Label15 = uilabel(app.Technical);
            app.Label15.Text = 'Avg Load';
            app.Label16 = uilabel(app.Technical);
            app.Label16.Text = 'Peak Load';
            app.Label17 = uilabel(app.Technical);
            app.Label17.Text = 'Units Consumed';
            app.Label18 = uilabel(app.Technical);
            app.Label18.Text = 'Max Demand';
            app.Label19 = uilabel(app.Technical);
            app.Label19.Text = 'VALUE';
            app.Label20 = uilabel(app.Technical);
            app.Label20.Text = 'VALUE';
            app.Label21 = uilabel(app.Technical);
            app.Label21.Text = 'VALUE';
            app.Label22 = uilabel(app.Technical);
            app.Label22.Text = 'VALUE';
        end
    end

    methods (Access = public)

        % Construct app
        function app = PG_App()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end